classdef Taza01_byPro < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        TabGroup      matlab.ui.container.TabGroup
        level1Tab     matlab.ui.container.Tab
        level2Tab     matlab.ui.container.Tab
        Label_2       matlab.ui.control.Label
        CounterLabel  matlab.ui.control.Label
        Label         matlab.ui.control.Label
        UIAxes        matlab.ui.control.UIAxes
        InputLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        aOk  % 맞았을 때
        aErr % 틀렸을 때
        L    % 화면에 있는 글자
        
        score = 0 % 점수
        T = timer % 1분 제한시간 타이머
        counter = 4; % 남은 시간 (sec)
        
        drop = timer %떨어질때 업데이트해줄
        
        new_word = timer %2초 간격으로 새로 만들어줄
        
        
        height = 13.5;% 떨어지는 높이
        v = 0.5;%떨어지는 속도
        
        msg1; % 게임오버 메시지
        msg2;  
        msg3;
        
        arr = {'apple','banana','Cc'};
        
        answer = [];
        index = 0;
        enter_answer = {};
        
        
        index_1 = 0;
    end
    
    methods (Access = private)
        
        function count(app, obj, event)
            app.counter = app.counter - 1;
            app.CounterLabel.Text = int2str(app.counter);
            if app.counter <= 0
                stop(app.T);
                stop(app.drop);
                if app.counter == 0
                    app.msg1=text(app.UIAxes,0,0,'[GAME OVER]',"FontSize",50,"HorizontalAlignment","center");
                    app.msg2=text(app.UIAxes,0,4,'[Score]',"FontSize",40,"HorizontalAlignment","center");
                    app.msg3=text(app.UIAxes,0,2.3,num2str(app.score),"FontSize",40,"HorizontalAlignment","center");

                end
            end
        end

        
        function drop_count(app, obj, event)
            app.L.Position(2)=app.L.Position(2)-app.v; %글자 떨어뜨리기
            app.L.Position(2);
            if(app.L.Position(2)<-10)
                delete(app.L); %객체 삭제
                app.score = app.score - 1; %떨어진거 놓치면 점수 -1
                app.Label.Text = int2str(app.score);
                create_letter(app);
            end
        end
        
        function create_letter(app,obj, event)
    
            %c = char('A' + randi([0 25]));
            c = randi(length(app.arr))
            app.L = text(app.UIAxes, 0,0,char(app.arr(2)), "FontSize", 24);
            r = randi([-10 9]) % 좌우 랜덤
            app.L.Position = [ r app.height 0];
        end
        
        function newword_count(app, obj, event)
            disp('야호')

            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            create_letter(app)
            
            [y,Fs] = audioread('chime.mp3');
            app.aOk = audioplayer(y,Fs);
            [y1,Fs1] = audioread('click.mp3');
            app.aErr = audioplayer(y1,Fs1);
            

            app.T.ExecutionMode = 'fixedRate';
            app.T.Period        = 1;
            app.T.TimerFcn      = @app.count;
            
            app.drop.ExecutionMode = 'fixedRate';
            app.drop.Period        = 0.1;
            app.drop.TimerFcn      = @app.drop_count;
            
            app.new_word.ExecutionMode = 'fixedRate';
            app.new_word.Period        = 3; %2초 간격 생성
            app.new_word.TimerFcn      = @app.newword_count;
            
            
            start(app.T);
            start(app.drop);
            start(app.new_word);

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            disp(double(key));
%               if upper(key) == app.L.String
%                 disp('Correct');
%                 app.score = app.score + 1;
%                 app.Label.Text = int2str(app.score);
%                 if isplaying(app.aOk)
%                     stop(app.aOk);
%                 end
%                 play(app.aOk);
%                 c = char('A' + randi([0 25]));
%                 app.L.String = c;
%                 r = randi([-10 9]) % 좌우 랜덤
%                 app.L.Position = [ r app.height 0];
%             else
%                 disp('Error');
%                 play(app.aErr);
%             end

             if isequal(key,'space') % 스페이스 누르면 초기화
                app.score = 0;
                app.Label.Text = int2str(app.score);
                app.counter = 60;
                app.CounterLabel.Text = int2str(app.counter);
                stop(app.T);
                start(app.T);
                delete(app.msg1);
                delete(app.msg2);
                delete(app.msg3);
                delete(app.L);
                stop(app.drop);
                create_letter(app);
                start(app.drop);
             end
             if isequal(key,'return') % 엔터누르면 동작
                 app.enter_answer={char(app.answer)}
                 disp('엔터후 답안')
                 disp(app.enter_answer);
                 app.index=0;
                 app.answer=[];
                 app.InputLabel.Text = (char(app.answer));
             elseif ~isequal(key,'space') % 타자 입력
                 if double(key) >= 97 && double(key) <= 122
                    app.index = app.index + 1;
                    app.answer(app.index) = double(key);
                    app.InputLabel.Text = (char(app.answer));
                    disp(char(app.answer))
                 end
                 
             end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.T);
            delete(app.T);
            stop(app.drop);
            delete(app.drop);
            stop(app.new_word);
            delete(app.new_word);
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.InputLabel = uilabel(app.UIFigure);
            app.InputLabel.Text = '이곳에 타자가 입력됩니다..';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.level1Tab = uitab(app.TabGroup);
            app.level1Tab.Title = 'level1';
            app.level2Tab = uitab(app.TabGroup);
            app.level2Tab.Title = 'level2';
            app.UIAxes = uiaxes(app.level2Tab);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-10 10];
            app.UIAxes.YLim = [-10 10];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Label = uilabel(app.level2Tab);
            app.Label.Text = '0';
            app.CounterLabel = uilabel(app.level2Tab);
            app.CounterLabel.Text = '60';
            app.Label_2 = uilabel(app.level2Tab);
            app.Label_2.Text = '타자입력공간 : [                                                 ]    ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Taza01_byPro

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end